classdef Voyager_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Main_UI              matlab.ui.Figure
        Button_2             matlab.ui.control.Button
        Button               matlab.ui.control.Button
        ExportimageButton    matlab.ui.control.Button
        Edgefilter           matlab.ui.control.CheckBox
        PlayButton           matlab.ui.control.StateButton
        SecondsLabel         matlab.ui.control.Label
        Current_frame_field  matlab.ui.control.Spinner
        CurrenttimeLabel_3   matlab.ui.control.Label
        FramesSlider         matlab.ui.control.Slider
        FrameselectionLabel  matlab.ui.control.Label
        Step_size_field      matlab.ui.control.NumericEditField
        CurrenttimeLabel_2   matlab.ui.control.Label
        Title                matlab.ui.control.Label
        Channel_selection    matlab.ui.container.ButtonGroup
        Channel_2_button     matlab.ui.control.ToggleButton
        Channel_1_button     matlab.ui.control.ToggleButton
        Image_figure         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Selected_chennel
        Channel_1_audio
        Channel_2_audio
        Channel_1_audio_temp
        Channel_2_audio_temp
        Voyager_audio_Fs

        Step_size
        Current_frame
        Current_window
            
        Sharpening_filter
        Timestamps
        current_image_ID
        H
        H2
        H3
        H4
        Test_Block
    end

    methods (Access = private)

        function Update_image(app)
            Reduced_block_size =513*734;
            Signal = app.Selected_chennel(fix(app.Current_frame)+1:2:fix(app.Current_frame)+Reduced_block_size);
            New_fs = app.change_frequency;
            Signal = resample(Signal,New_fs,44100);
            Signal(end-mod(length(Signal),512)+1:end) = [];
            Signal = imcomplement(Signal);
            app.Current_window = reshape(Signal,[],512);
            

            if app.Edgefilter.Value
                edgefilt = [-1 -1 -1;0 0 0;1 1 1];
                imshow(filter2(edgefilt,app.Current_window),[],'Parent',app.Image_figure);
            else
                imshow(app.Current_window,[],'Parent',app.Image_figure);
            end
                plot(app.H,Signal);
                    x = app.Selected_chennel(fix(app.Current_frame)+1:2:fix(app.Current_frame)+Reduced_block_size);
                n = length(x);
                y = fftshift(fft(x));
                
                f = (0:n-1)*(New_fs/n); 
                power = abs(y).^2/n;
                plot(app.H2,f,power);
                
                x = x(1:366);
                n = length(x);
                y = fftshift(fft(x));
                
                f = (0:n-1)*(New_fs/n); 
                power = abs(y).^2/n;
                plot(app.H3,f,power);

                imshow(app.Current_window,[],'Parent',app.H4)
                Template = app.Current_window(1:15,1:15);
                T = norm(Template - app.Test_Block)
                
        end

        
        function New_fs = change_frequency(app)

            if strcmp(app.Channel_selection.SelectedObject.Text,"Channel 1")
                Closest_ID = interp1(app.Timestamps(:,2),app.Timestamps(:,1),app.Current_frame_field.Value,'nearest','extrap');
                New_fs = app.Timestamps(Closest_ID+1,3);
            else
                Closest_ID = interp1(app.Timestamps(:,4),app.Timestamps(:,1),app.Current_frame_field.Value,'nearest','extrap');
                New_fs = app.Timestamps(Closest_ID+1,5);
            end         
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             Read audio signal and devide into channels:
            [Voyager_audio1,app.Voyager_audio_Fs] = audioread('voyager.mp3');
            [Voyager_audio,app.Voyager_audio_Fs] = audioread('Personal_Golden_Record_Audio.wav');
            app.Channel_1_audio = Voyager_audio(:,1);
            app.Channel_2_audio = Voyager_audio(:,2);
            app.Selected_chennel = app.Channel_1_audio;

            %             Initialize parameters and figure:
            app.Step_size = 5000;
            app.Step_size_field.Value = app.Step_size;
            app.Timestamps = load('Timestamps.mat');
            app.Timestamps = cell2mat(struct2cell(app.Timestamps));
            app.Current_frame = 0;
            app.Current_frame_field.Value = app.Current_frame;
            app.Current_frame_field.Step = app.Step_size/app.Voyager_audio_Fs;
            app.current_image_ID = -1;
            app.Test_Block = load('Test_Block.mat');
            app.Test_Block = cell2mat(struct2cell(app.Test_Block));

             
            figure;
            app.H = axes;
            figure;
            app.H2 = axes;
            figure;
            app.H3 = axes;
            figure;
            app.H4 = axes;
            %           Populate figure with startup sliding window:
            app.Update_image

            %             Define Slider limits:
            app.FramesSlider.Limits = [1,(length(Voyager_audio(:,1))-375808+1)/app.Step_size];

        end

        % Selection changed function: Channel_selection
        function Change_channel(app, event)
            app.ButtonPushed;
            if strcmp(app.Channel_selection.SelectedObject.Text,"Channel 1")
                app.Selected_chennel = app.Channel_1_audio;
            else
                app.Selected_chennel = app.Channel_2_audio;
            end

            app.Update_image;
        end

        % Value changed function: FramesSlider
        function Slider_chenge_value(app, event)
            %             Update image:
            app.Current_frame = app.FramesSlider.Value*app.Step_size;
            app.Update_image;

            %             Update time frame:
            app.Current_frame_field.Value = app.Current_frame/app.Voyager_audio_Fs;
        end

        % Value changed function: Step_size_field
        function Step_size_changed(app, event)
            app.Step_size = app.Step_size_field.Value;

            %             Update slider and spiner:
            app.FramesSlider.Limits = [1,(length(app.Channel_1_audio)-375808+1)/app.Step_size];
            app.Current_frame_field.Step = app.Step_size/app.Voyager_audio_Fs;
        end

        % Value changing function: Current_frame_field
        function Current_frame_change(app, event)
            %             Update slider:
            app.FramesSlider.Value = app.Voyager_audio_Fs*event.Value/app.Step_size;

            %             Update image:
            app.Current_frame = event.Value*app.Voyager_audio_Fs;
            app.Update_image;

        end

        % Value changed function: PlayButton
        function Play_callback(app, event)
            while app.PlayButton.Value
                app.Current_frame_field.Value = app.Current_frame_field.Value + app.Current_frame_field.Step;
                app.Current_frame = app.Voyager_audio_Fs*app.Current_frame_field.Value;
                app.FramesSlider.Value = app.Current_frame/app.Step_size;
                app.Update_image;
                pause(0.1)
                %                     java.lang.Thread.sleep(1000*1000)
            end


        end

        % Callback function
        function Brightness_change(app, event)
            app.Sharpening_filter = [0,-app.Edges_enhancement.Value,0;...
                -app.Edges_enhancement.Value,-1+4*app.Edges_enhancement.Value,-app.Edges_enhancement.Value;...
                0,-app.Edges_enhancement.Value,0];
            app.Update_image;

        end

        % Value changed function: Edgefilter
        function EdgefilterValueChanged(app, event)
            app.Update_image;
        end

        % Button pushed function: ExportimageButton
        function ExportimageButtonPushed(app, event)
            prompt = {'Enter image name:'};
            dlgtitle = 'Save image';
            answer = inputdlg(prompt,dlgtitle);
            if ~isempty(answer)
                currentFolder = pwd;
                if strcmp(app.Channel_selection.SelectedObject.Text,"Channel 1")
                    imwrite(mat2gray(app.Current_window),fullfile(strcat(currentFolder,'\Images-Channel 1'),strcat(answer{1,1},'.png')))
                else
                    imwrite(mat2gray(app.Current_window),fullfile(strcat(currentFolder,'\Images-Channel 2'),strcat(answer{1,1},'.png')))
                end
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.current_image_ID = min(size(app.Timestamps,1),app.current_image_ID + 1);
            if strcmp(app.Channel_selection.SelectedObject.Text,"Channel 1")
                Current_timestamp = app.Timestamps(app.current_image_ID+1,2);
            else
                Current_timestamp = app.Timestamps(app.current_image_ID+1,4);
            end
            app.FramesSlider.Value = app.Voyager_audio_Fs*Current_timestamp/app.Step_size;

            %             Update image:
            app.Current_frame = Current_timestamp*app.Voyager_audio_Fs;
            app.Current_frame_field.Value = app.Current_frame/app.Voyager_audio_Fs;
            app.Update_image;

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.current_image_ID = max(0,app.current_image_ID - 1);
            if strcmp(app.Channel_selection.SelectedObject.Text,"Channel 1")
                Current_timestamp = app.Timestamps(app.current_image_ID+1,2);
            else
                Current_timestamp = app.Timestamps(app.current_image_ID+1,4);
            end
            app.FramesSlider.Value = app.Voyager_audio_Fs*Current_timestamp/app.Step_size;

            %             Update image:
            app.Current_frame = Current_timestamp*app.Voyager_audio_Fs;
            app.Current_frame_field.Value = app.Current_frame/app.Voyager_audio_Fs;
            app.Update_image;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Main_UI = uifigure('Visible', 'off');
            app.Main_UI.Name = 'MATLAB App';
            app.Image_figure = uiaxes(app.Main_UI);
            zlabel(app.Image_figure, 'Z')
            app.Channel_selection = uibuttongroup(app.Main_UI);
            app.Channel_selection.SelectionChangedFcn = createCallbackFcn(app, @Change_channel, true);
            app.Channel_selection.Title = 'Channel selection';
            app.Channel_1_button = uitogglebutton(app.Channel_selection);
            app.Channel_1_button.Text = 'Channel 1';
            app.Channel_1_button.Value = true;
            app.Channel_2_button = uitogglebutton(app.Channel_selection);
            app.Channel_2_button.Text = 'Channel 2';
            app.Title = uilabel(app.Main_UI);
            app.Title.Text = 'Voyager Golden Recod';
            app.CurrenttimeLabel_2 = uilabel(app.Main_UI);
            app.CurrenttimeLabel_2.Text = 'Step size';
            app.Step_size_field = uieditfield(app.Main_UI, 'numeric');
            app.Step_size_field.LowerLimitInclusive = 'off';
            app.Step_size_field.Limits = [0 Inf];
            app.Step_size_field.RoundFractionalValues = 'on';
            app.Step_size_field.ValueChangedFcn = createCallbackFcn(app, @Step_size_changed, true);
            app.Step_size_field.Value = 1;
            app.FrameselectionLabel = uilabel(app.Main_UI);
            app.FrameselectionLabel.Text = 'Frame selection';
            app.FramesSlider = uislider(app.Main_UI);
            app.FramesSlider.MajorTicks = [];
            app.FramesSlider.MajorTickLabels = {''};
            app.FramesSlider.ValueChangedFcn = createCallbackFcn(app, @Slider_chenge_value, true);
            app.FramesSlider.MinorTicks = [];
            app.CurrenttimeLabel_3 = uilabel(app.Main_UI);
            app.CurrenttimeLabel_3.Text = 'Current time';
            app.Current_frame_field = uispinner(app.Main_UI);
            app.Current_frame_field.ValueChangingFcn = createCallbackFcn(app, @Current_frame_change, true);
            app.Current_frame_field.Limits = [0 Inf];
            app.Current_frame_field.ValueDisplayFormat = '%.3f';
            app.SecondsLabel = uilabel(app.Main_UI);
            app.SecondsLabel.Text = 'Seconds';
            app.PlayButton = uibutton(app.Main_UI, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @Play_callback, true);
            app.PlayButton.Icon = 'slrtRunIcon.png';
            app.PlayButton.Text = 'Play';
            app.Edgefilter = uicheckbox(app.Main_UI);
            app.Edgefilter.ValueChangedFcn = createCallbackFcn(app, @EdgefilterValueChanged, true);
            app.Edgefilter.Text = 'Edge filter';
            app.ExportimageButton = uibutton(app.Main_UI, 'push');
            app.ExportimageButton.ButtonPushedFcn = createCallbackFcn(app, @ExportimageButtonPushed, true);
            app.ExportimageButton.Text = 'Export image';
            app.Button = uibutton(app.Main_UI, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'icon_addons_leftarrow_blue_16.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.Main_UI, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'icon_addons_rightarrow_blue_16.png';
            app.Button_2.Text = '';
            % Show the figure after all components are created
            app.Main_UI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Voyager_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Main_UI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Main_UI)
        end
    end
end